classdef calibGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FirstStepLabel                  matlab.ui.control.Label
        AlignDUMMYButton                matlab.ui.control.Button
        TakeAReferenceButton            matlab.ui.control.Button
        AlignDUMMYResult                matlab.ui.control.Label
        ReferenceResult                 matlab.ui.control.Label
        SecondStepLabel                 matlab.ui.control.Label
        AlignDMButton                   matlab.ui.control.Button
        SecondStepLabel_2               matlab.ui.control.Label
        AlignDMResult                   matlab.ui.control.Label
        StartTemperatureRegulationButton  matlab.ui.control.Button
        TemperatureRegulationResult     matlab.ui.control.Label
        SelectaDMLabel                  matlab.ui.control.Label
        DMChoices                       matlab.ui.control.DropDown
        SecondStepLabel_3               matlab.ui.control.Label
        CheckDefaultInteractionMatrixButton  matlab.ui.control.Button
        CheckDefaultMatrixResult        matlab.ui.control.Label
        SecondStepLabel_4               matlab.ui.control.Label
        MakenewInteractionMatrixButton  matlab.ui.control.Button
        SecondStepLabel_5               matlab.ui.control.Label
        CheckNewMatrixButton            matlab.ui.control.Button
        MakeNewMatrixResult             matlab.ui.control.Label
        SecondStepLabel_6               matlab.ui.control.Label
        CreatePDFReportButton           matlab.ui.control.Button
        MakeNewMatrixResult_2           matlab.ui.control.Label
        StopTemperatureRegulationButton  matlab.ui.control.Button
        WarmBenchatambienttemperatureButton  matlab.ui.control.Button
        PdfReportResult                 matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        bench; % Description
        okColor = [0 1.0 0];
        notOkColor = [1.0 0.0 0.0];
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            if app.bench.isAligned
                app.AlignDUMMYResult.Text = 'ok';
                app.AlignDUMMYResult.FontColor = app.okColor;
            else
                app.AlignDUMMYResult.Text = 'not aligned ';
                app.AlignDUMMYResult.FontColor = app.notOkColor;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            app.bench = bench;
        end

        % Button pushed function: AlignDUMMYButton
        function AlignDUMMYButtonPushed(app, event)
            alignmentGui(app.bench);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FirstStepLabel = uilabel(app.UIFigure);
            app.FirstStepLabel.Text = '1. Take a bench wavefront reference  with the DUMMY mirror inside the bench [1 minute]';
            app.AlignDUMMYButton = uibutton(app.UIFigure, 'push');
            app.AlignDUMMYButton.ButtonPushedFcn = createCallbackFcn(app, @AlignDUMMYButtonPushed, true);
            app.AlignDUMMYButton.Text = 'Align DUMMY';
            app.TakeAReferenceButton = uibutton(app.UIFigure, 'push');
            app.TakeAReferenceButton.Text = 'Take A Reference';
            app.AlignDUMMYResult = uilabel(app.UIFigure);
            app.AlignDUMMYResult.Text = 'Not aligned';
            app.ReferenceResult = uilabel(app.UIFigure);
            app.ReferenceResult.Text = 'No reference';
            app.SecondStepLabel = uilabel(app.UIFigure);
            app.SecondStepLabel.Text = '2. Align DM at ambient temperature [1minute]';
            app.AlignDMButton = uibutton(app.UIFigure, 'push');
            app.AlignDMButton.Text = 'Align DM';
            app.SecondStepLabel_2 = uilabel(app.UIFigure);
            app.SecondStepLabel_2.Text = '3. Start Temperature Regulation (reach 10 degree)  [~1.5 hour if ambient at 20 degree] ';
            app.AlignDMResult = uilabel(app.UIFigure);
            app.AlignDMResult.Text = 'Not Aligned';
            app.StartTemperatureRegulationButton = uibutton(app.UIFigure, 'push');
            app.StartTemperatureRegulationButton.Text = 'Start Temperature Regulation';
            app.TemperatureRegulationResult = uilabel(app.UIFigure);
            app.TemperatureRegulationResult.Text = 'XX degree';
            app.SelectaDMLabel = uilabel(app.UIFigure);
            app.SelectaDMLabel.Text = 'Select a DM';
            app.DMChoices = uidropdown(app.UIFigure);
            app.SecondStepLabel_3 = uilabel(app.UIFigure);
            app.SecondStepLabel_3.Text = '3. Check The Default Interaction Matrix [5 minutes] ';
            app.CheckDefaultInteractionMatrixButton = uibutton(app.UIFigure, 'push');
            app.CheckDefaultInteractionMatrixButton.Text = 'Check Default Interaction Matrix';
            app.CheckDefaultMatrixResult = uilabel(app.UIFigure);
            app.CheckDefaultMatrixResult.Text = '------- ';
            app.SecondStepLabel_4 = uilabel(app.UIFigure);
            app.SecondStepLabel_4.Text = '4. Make a new interaction matrix and bias  [0.5 hours] ';
            app.MakenewInteractionMatrixButton = uibutton(app.UIFigure, 'push');
            app.MakenewInteractionMatrixButton.Text = 'Make new Interaction Matrix';
            app.SecondStepLabel_5 = uilabel(app.UIFigure);
            app.SecondStepLabel_5.Text = '4. Check the new Interaction Matrix [5 minutes]';
            app.CheckNewMatrixButton = uibutton(app.UIFigure, 'push');
            app.CheckNewMatrixButton.Text = 'Check New Matrix';
            app.MakeNewMatrixResult = uilabel(app.UIFigure);
            app.MakeNewMatrixResult.Text = '------- ';
            app.SecondStepLabel_6 = uilabel(app.UIFigure);
            app.SecondStepLabel_6.Text = '5. Warm up and Create PDF report';
            app.CreatePDFReportButton = uibutton(app.UIFigure, 'push');
            app.CreatePDFReportButton.Text = 'Create PDF Report';
            app.MakeNewMatrixResult_2 = uilabel(app.UIFigure);
            app.MakeNewMatrixResult_2.Text = '------- ';
            app.StopTemperatureRegulationButton = uibutton(app.UIFigure, 'push');
            app.StopTemperatureRegulationButton.Text = 'Stop Temperature Regulation';
            app.WarmBenchatambienttemperatureButton = uibutton(app.UIFigure, 'push');
            app.WarmBenchatambienttemperatureButton.Text = 'Warm Bench at ambient temperature';
            app.PdfReportResult = uilabel(app.UIFigure);
            app.PdfReportResult.Text = '------- ';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = calibGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end