classdef startupGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MirorDropDownLabel    matlab.ui.control.Label
        MirorDropDown         matlab.ui.control.DropDown
        EnvironmentStartup    matlab.ui.control.Button
        EnvironmentShutdown   matlab.ui.control.Button
        EnvironmentLampLabel  matlab.ui.control.Label
        EnvironmentLamp       matlab.ui.control.Lamp
        GimbalStartup         matlab.ui.control.Button
        GimbalShutdown        matlab.ui.control.Button
        GimbalLampLabel       matlab.ui.control.Label
        GimbalLamp            matlab.ui.control.Lamp
        WaveFrontStartup      matlab.ui.control.Button
        WaveFrontShutdown     matlab.ui.control.Button
        WaveFrontLampLabel    matlab.ui.control.Label
        WaveFrontLamp         matlab.ui.control.Lamp
        DMStartup             matlab.ui.control.Button
        DMShutdown            matlab.ui.control.Button
        DMLampLabel           matlab.ui.control.Label
        DMLamp                matlab.ui.control.Lamp
        RefreshButton         matlab.ui.control.Button
    end


    properties (Access = public)
        onlineColor = [0 1.0 0.0]; % Description
        offlineColor = [1.0, 0.0, 0.0]; 
        bench;
        appTimer;
        
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            isDummy = strcmp(app.bench.config.dmId, app.bench.config.DUMMY);
            
            if app.bench.has('environment')
                app.EnvironmentLamp.Color = app.onlineColor;
                app.EnvironmentShutdown.Enable = 'on';
                app.EnvironmentStartup.Enable = 'off';
            else
                app.EnvironmentLamp.Color = app.offlineColor;
                app.EnvironmentShutdown.Enable = 'off';
                app.EnvironmentStartup.Enable = 'on';
            end
            
            if isDummy
                app.GimbalLamp.Color = app.offlineColor;
                app.GimbalShutdown.Enable = 'off';
                app.GimbalStartup.Enable = 'off';
                app.DMLamp.Color = app.offlineColor;
                app.DMShutdown.Enable = 'off';
                app.DMStartup.Enable = 'off';
            else
                if app.bench.has('gimbal')
                    app.GimbalLamp.Color = app.onlineColor;
                    app.GimbalShutdown.Enable = 'on';
                    app.GimbalStartup.Enable = 'off';
                else
                    app.GimbalLamp.Color = app.offlineColor;
                    app.GimbalShutdown.Enable = 'off';
                    app.GimbalStartup.Enable = 'on';
                end
                
                if app.bench.has('dm')
                    app.DMLamp.Color = app.onlineColor;
                    app.DMShutdown.Enable = 'on';
                    app.DMStartup.Enable = 'off';
                else
                    app.DMLamp.Color = app.offlineColor;
                    app.DMShutdown.Enable = 'off';
                    app.DMStartup.Enable = 'on';
                end
                
         
            end
            
            if app.bench.has('wfs')
                    app.WaveFrontLamp.Color = app.onlineColor;
                    app.WaveFrontShutdown.Enable = 'on';
                    app.WaveFrontStartup.Enable = 'off';
            else
                    app.WaveFrontLamp.Color = app.offlineColor;
                    app.WaveFrontShutdown.Enable = 'off';
                    app.WaveFrontStartup.Enable = 'on';
            end


        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            global naomiBench
            if nargin<2
                if ~exist('naomiBench') || isempty(naomiBench)
                    naomiStartup;
                end
                bench = naomiBench;
            end
            app.bench = bench;
            app.appTimer = timer('Period',2.0,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.appTimer.TimerFcn = @app.refresh;
            app.MirorDropDown.Items = app.bench.config.dmIdChoices;
            if bench.config.dmId
                app.MirorDropDown.Value = app.bench.config.dmId;
            end
            %start(app.appTimer);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refresh;
        end

        % Button pushed function: EnvironmentStartup
        function EnvironmentStartupButtonPushed(app, event)
            app.bench.startEnvironment();
            app.refresh;
        end

        % Button pushed function: EnvironmentShutdown
        function EnvironmentShutdownButtonPushed(app, event)
            app.bench.stopEnvironment();
            app.refresh;
        end

        % Button pushed function: GimbalStartup
        function GimbalStartupButtonPushed(app, event)
            app.bench.startGimbal();
            app.refresh;
        end

        % Button pushed function: GimbalShutdown
        function GimbalShutdownButtonPushed(app, event)
            app.bench.stopGimbal();
            app.refresh;
        end

        % Button pushed function: WaveFrontStartup
        function WaveFrontStartupButtonPushed(app, event)
            app.bench.startWfs();
            app.refresh;
        end

        % Button pushed function: WaveFrontShutdown
        function WaveFrontShutdownButtonPushed(app, event)
            app.bench.stopWfs();
            app.refresh;
        end

        % Button pushed function: DMStartup
        function DMStartupButtonPushed(app, event)
            app.bench.startDm();
            app.refresh;
        end

        % Button pushed function: DMShutdown
        function DMShutdownButtonPushed(app, event)
            app.bench.stopDm();
            app.refresh;
        end

        % Value changed function: MirorDropDown
        function MirorDropDownValueChanged(app, event)
            value = app.MirorDropDown.Value;
            if ~strcmp(app.bench.config.dmId, value)
                app.bench.stopDm();
            end
            app.bench.config.dmId = value;
            app.refresh;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MirorDropDownLabel = uilabel(app.UIFigure);
            app.MirorDropDownLabel.Text = 'Miror';
            app.MirorDropDown = uidropdown(app.UIFigure);
            app.MirorDropDown.ValueChangedFcn = createCallbackFcn(app, @MirorDropDownValueChanged, true);
            app.EnvironmentStartup = uibutton(app.UIFigure, 'push');
            app.EnvironmentStartup.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentStartupButtonPushed, true);
            app.EnvironmentStartup.Text = 'Startup';
            app.EnvironmentShutdown = uibutton(app.UIFigure, 'push');
            app.EnvironmentShutdown.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentShutdownButtonPushed, true);
            app.EnvironmentShutdown.Text = 'Shutdown';
            app.EnvironmentLampLabel = uilabel(app.UIFigure);
            app.EnvironmentLampLabel.Text = 'Environment ';
            app.EnvironmentLamp = uilamp(app.UIFigure);
            app.GimbalStartup = uibutton(app.UIFigure, 'push');
            app.GimbalStartup.ButtonPushedFcn = createCallbackFcn(app, @GimbalStartupButtonPushed, true);
            app.GimbalStartup.Text = 'Startup';
            app.GimbalShutdown = uibutton(app.UIFigure, 'push');
            app.GimbalShutdown.ButtonPushedFcn = createCallbackFcn(app, @GimbalShutdownButtonPushed, true);
            app.GimbalShutdown.Text = 'Shutdown';
            app.GimbalLampLabel = uilabel(app.UIFigure);
            app.GimbalLampLabel.Text = 'Gimbal';
            app.GimbalLamp = uilamp(app.UIFigure);
            app.WaveFrontStartup = uibutton(app.UIFigure, 'push');
            app.WaveFrontStartup.ButtonPushedFcn = createCallbackFcn(app, @WaveFrontStartupButtonPushed, true);
            app.WaveFrontStartup.Text = 'Startup';
            app.WaveFrontShutdown = uibutton(app.UIFigure, 'push');
            app.WaveFrontShutdown.ButtonPushedFcn = createCallbackFcn(app, @WaveFrontShutdownButtonPushed, true);
            app.WaveFrontShutdown.Text = 'Shutdown';
            app.WaveFrontLampLabel = uilabel(app.UIFigure);
            app.WaveFrontLampLabel.Text = 'Wave Front';
            app.WaveFrontLamp = uilamp(app.UIFigure);
            app.DMStartup = uibutton(app.UIFigure, 'push');
            app.DMStartup.ButtonPushedFcn = createCallbackFcn(app, @DMStartupButtonPushed, true);
            app.DMStartup.Text = 'Startup';
            app.DMShutdown = uibutton(app.UIFigure, 'push');
            app.DMShutdown.ButtonPushedFcn = createCallbackFcn(app, @DMShutdownButtonPushed, true);
            app.DMShutdown.Text = 'Shutdown';
            app.DMLampLabel = uilabel(app.UIFigure);
            app.DMLampLabel.Text = 'DM';
            app.DMLamp = uilamp(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = startupGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end